classdef ColorConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorConversionToolbox         matlab.ui.Figure
        ConverterPanel                 matlab.ui.container.Panel
        XYZButton                      matlab.ui.control.Button
        LuvButton                      matlab.ui.control.Button
        LabButton                      matlab.ui.control.Button
        sRGBButton                     matlab.ui.control.Button
        AdobeRGBButton                 matlab.ui.control.Button
        CustomDefinedRGBButton         matlab.ui.control.Button
        XYZ_X                          matlab.ui.control.NumericEditField
        XYZ_Y                          matlab.ui.control.NumericEditField
        XYZ_Z                          matlab.ui.control.NumericEditField
        Luv_L                          matlab.ui.control.NumericEditField
        Luv_u                          matlab.ui.control.NumericEditField
        Luv_v                          matlab.ui.control.NumericEditField
        Lab_L                          matlab.ui.control.NumericEditField
        Lab_a                          matlab.ui.control.NumericEditField
        Lab_b                          matlab.ui.control.NumericEditField
        sRGB_R                         matlab.ui.control.NumericEditField
        sRGB_G                         matlab.ui.control.NumericEditField
        sRGB_B                         matlab.ui.control.NumericEditField
        AdobeRGB_R                     matlab.ui.control.NumericEditField
        AdobeRGB_G                     matlab.ui.control.NumericEditField
        AdobeRGB_B                     matlab.ui.control.NumericEditField
        RGB_R                          matlab.ui.control.NumericEditField
        RGB_G                          matlab.ui.control.NumericEditField
        RGB_B                          matlab.ui.control.NumericEditField
        xyYButton                      matlab.ui.control.Button
        xyY_x                          matlab.ui.control.NumericEditField
        xyY_y                          matlab.ui.control.NumericEditField
        xyY_Y                          matlab.ui.control.NumericEditField
        SpectrumPanel                  matlab.ui.container.Panel
        BlackbodyradiationButton       matlab.ui.control.Button
        SpectrumFileButton             matlab.ui.control.Button
        NormalizeYto100CheckBox        matlab.ui.control.CheckBox
        TKEditFieldLabel               matlab.ui.control.Label
        BlackTemp                      matlab.ui.control.NumericEditField
        CustomdefinedRGBspacePanel     matlab.ui.container.Panel
        R_X                            matlab.ui.control.NumericEditField
        R_Y                            matlab.ui.control.NumericEditField
        R_Z                            matlab.ui.control.NumericEditField
        G_X                            matlab.ui.control.NumericEditField
        G_Y                            matlab.ui.control.NumericEditField
        G_Z                            matlab.ui.control.NumericEditField
        B_X                            matlab.ui.control.NumericEditField
        B_Y                            matlab.ui.control.NumericEditField
        B_Z                            matlab.ui.control.NumericEditField
        W_X                            matlab.ui.control.NumericEditField
        W_Y                            matlab.ui.control.NumericEditField
        W_Z                            matlab.ui.control.NumericEditField
        XYZLabel                       matlab.ui.control.Label
        PrimaryRedGreenBlueWhiteLabel  matlab.ui.control.Label
        GammaEditFieldLabel            matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.NumericEditField
        ColorvisiondeficiencyPanel     matlab.ui.container.Panel
        ColorShow                      matlab.ui.control.UIAxes
        NormalLabel                    matlab.ui.control.Label
        ColorShow_2                    matlab.ui.control.UIAxes
        ColorShow_3                    matlab.ui.control.UIAxes
        ProtanopiaLabel                matlab.ui.control.Label
        DeuteranopiaLabel              matlab.ui.control.Label
        TritanopiaLabel                matlab.ui.control.Label
        ColorShow_4                    matlab.ui.control.UIAxes
        LoadImageFileButton            matlab.ui.control.Button
    end


    methods (Access = public)
    
        function UpdateNum(app,XYZ,str)
            
            if ~strcmp(str,'XYZ')
                app.XYZ_X.Value = XYZ(1);
                app.XYZ_Y.Value = XYZ(2);
                app.XYZ_Z.Value = XYZ(3);
            end
            
            if ~strcmp(str,'xyY')
                xyY = XYZ2xyY(XYZ);
                app.xyY_x.Value = xyY(1);
                app.xyY_y.Value = xyY(2);
                app.xyY_Y.Value = xyY(3);
            end
            
            if ~strcmp(str,'RGB')
                RGB = XYZ2Arbitrary(...
                    XYZ,...
                    [app.R_X.Value app.R_Y.Value app.R_Z.Value],...
                    [app.G_X.Value app.G_Y.Value app.G_Z.Value],...
                    [app.B_X.Value app.B_Y.Value app.B_Z.Value],...
                    [app.W_X.Value app.W_Y.Value app.W_Z.Value],...
                    app.GammaEditField.Value);
                app.RGB_R.Value = RGB(1);
                app.RGB_G.Value = RGB(2);
                app.RGB_B.Value = RGB(3);
            end
            
            if ~strcmp(str,'AdobeRGB')
                AdobeRGB = XYZ2AdobeRGB(XYZ);
                app.AdobeRGB_R.Value = AdobeRGB(1);
                app.AdobeRGB_G.Value = AdobeRGB(2);
                app.AdobeRGB_B.Value = AdobeRGB(3);
            end
            
            if ~strcmp(str,'sRGB')
                sRGB = XYZ2sRGB(XYZ);
                app.sRGB_R.Value = sRGB(1);
                app.sRGB_G.Value = sRGB(2);
                app.sRGB_B.Value = sRGB(3);
            end
            
            if ~strcmp(str,'Luv')
                Luv = XYZ2Luv(XYZ,[app.W_X.Value app.W_Y.Value app.W_Z.Value]);
                Luv = clearNAN(app,Luv);
                app.Luv_L.Value = Luv(1);
                app.Luv_u.Value = Luv(2);
                app.Luv_v.Value = Luv(3);
            end
            
            if ~strcmp(str,'Lab')
                Lab = XYZ2Lab(XYZ,[app.W_X.Value app.W_Y.Value app.W_Z.Value]);
                app.Lab_L.Value = Lab(1);
                app.Lab_a.Value = Lab(2);
                app.Lab_b.Value = Lab(3);
            end
            
            ShowColor = max(min([app.sRGB_R.Value app.sRGB_G.Value app.sRGB_B.Value],[1 1 1]),[0 0 0]);
            [p,d,t] = dichromatic([app.sRGB_R.Value app.sRGB_G.Value app.sRGB_B.Value]);
            rectangle(app.ColorShow,'FaceColor',ShowColor,'Position',[0 0 1 1]);
            rectangle(app.ColorShow_2,'FaceColor',max(min(p,[1 1 1]),[0 0 0]),'Position',[0 0 1 1]);
            rectangle(app.ColorShow_3,'FaceColor',max(min(d,[1 1 1]),[0 0 0]),'Position',[0 0 1 1]);
            rectangle(app.ColorShow_4,'FaceColor',max(min(t,[1 1 1]),[0 0 0]),'Position',[0 0 1 1]);
        end
        
    end

    methods (Access = private)
    
        function x = clearNAN(app,x)
            x(isnan(x)) = 0;
            
        end
        
    end


    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.ColorShow, value*peaks)
            app.ColorShow.YLim = [-1000 1000];
        end

        % Button pushed function: XYZButton
        function XYZButtonPushed(app, event)
            UpdateNum(app,[app.XYZ_X.Value app.XYZ_Y.Value app.XYZ_Z.Value],'XYZ');
        end

        % Button pushed function: CustomDefinedRGBButton
        function CustomDefinedRGBButtonPushed(app, event)
            XYZ = Arbitrary2XYZ(...
                [app.RGB_R.Value app.RGB_G.Value app.RGB_B.Value],...
                [app.R_X.Value app.R_Y.Value app.R_Z.Value],...
                [app.G_X.Value app.G_Y.Value app.G_Z.Value],...
                [app.B_X.Value app.B_Y.Value app.B_Z.Value],...
                [app.W_X.Value app.W_Y.Value app.W_Z.Value],...
                app.GammaEditField.Value);
            UpdateNum(app,XYZ,'RGB');
        end

        % Button pushed function: sRGBButton
        function sRGBButtonPushed(app, event)
            XYZ = sRGB2XYZ([app.sRGB_R.Value app.sRGB_G.Value app.sRGB_B.Value]);
            UpdateNum(app,XYZ,'sRGB');
        end

        % Button pushed function: AdobeRGBButton
        function AdobeRGBButtonPushed(app, event)
            XYZ = AdobeRGB2XYZ([app.AdobeRGB_R.Value app.AdobeRGB_G.Value app.AdobeRGB_B.Value]);
            UpdateNum(app,XYZ,'AdobeRGB');
        end

        % Button pushed function: xyYButton
        function xyYButtonPushed(app, event)
            XYZ = xyY2XYZ([app.xyY_x.Value app.xyY_y.Value app.xyY_Y.Value]);
            UpdateNum(app,XYZ,'xyY');
        end

        % Button pushed function: LuvButton
        function LuvButtonPushed(app, event)
            XYZ = Luv2XYZ([app.Luv_L.Value app.Luv_u.Value app.Luv_v.Value],[app.W_X.Value app.W_Y.Value app.W_Z.Value]);
            UpdateNum(app,XYZ,'Luv');
        end

        % Button pushed function: LabButton
        function LabButtonPushed(app, event)
            XYZ = Lab2XYZ([app.Lab_L.Value app.Lab_a.Value app.Lab_b.Value],[app.W_X.Value app.W_Y.Value app.W_Z.Value]);
            UpdateNum(app,XYZ,'Luv');
        end

        % Button pushed function: BlackbodyradiationButton
        function BlackbodyradiationButtonPushed(app, event)
            [wl,sp] = blackbody(app.BlackTemp.Value);
            XYZ = Spectrum2XYZ(wl,sp);
            if app.NormalizeYto100CheckBox.Value
                if XYZ(2)
                    XYZ = 100.*XYZ./XYZ(2);
                end
            end
            UpdateNum(app,XYZ,'BB');
        end

        % Button pushed function: SpectrumFileButton
        function SpectrumFileButtonPushed(app, event)
            [fn,pt,ix] = uigetfile({'*.csv','CSV file(*.csv)';'*.xls;*.xlsx','Excel file(*.xls;*.xlsx)'});
            if(ix == 1)
                dt = csvread([pt fn]);
            elseif(ix == 2)
                dt = xlsread([pt fn]);
            else
                return;
            end
            XYZ = Spectrum2XYZ(dt(:,1),dt(:,2));
            if app.NormalizeYto100CheckBox.Value
                if XYZ(2)
                    XYZ = 100.*XYZ./XYZ(2);
                end
            end
            UpdateNum(app,XYZ,'Spectrum');
        end

        % Button pushed function: LoadImageFileButton
        function LoadImageFileButtonPushed(app, event)
            [fn,pt,ix] = uigetfile({'*.bmp;*.png;*.jpg;*.jpeg','Image file'});
            if(ix == 1)
                img = imread([pt fn]);
            else
                return;
            end
            [p,d,t] = dichromaticImage(img);
            figure();
            na = subplot(2,2,1);imshow(img,'Parent',na);title(na,'Normal');
            na = subplot(2,2,2);imshow(p,'Parent',na);title(na,'Protanopia');
            na = subplot(2,2,3);imshow(d,'Parent',na);title(na,'Deuteranopia');
            na = subplot(2,2,4);imshow(t,'Parent',na);title(na,'Tritanopia');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorConversionToolbox = uifigure;
            app.ColorConversionToolbox.Name = 'ColorConversionToolbox';
            app.ConverterPanel = uipanel(app.ColorConversionToolbox);
            app.ConverterPanel.Title = 'Converter';
            app.XYZButton = uibutton(app.ConverterPanel, 'push');
            app.XYZButton.ButtonPushedFcn = createCallbackFcn(app, @XYZButtonPushed, true);
            app.XYZButton.Text = 'XYZ';
            app.LuvButton = uibutton(app.ConverterPanel, 'push');
            app.LuvButton.ButtonPushedFcn = createCallbackFcn(app, @LuvButtonPushed, true);
            app.LuvButton.Text = 'Luv';
            app.LabButton = uibutton(app.ConverterPanel, 'push');
            app.LabButton.ButtonPushedFcn = createCallbackFcn(app, @LabButtonPushed, true);
            app.LabButton.Text = 'L*a*b*';
            app.sRGBButton = uibutton(app.ConverterPanel, 'push');
            app.sRGBButton.ButtonPushedFcn = createCallbackFcn(app, @sRGBButtonPushed, true);
            app.sRGBButton.Text = 'sRGB';
            app.AdobeRGBButton = uibutton(app.ConverterPanel, 'push');
            app.AdobeRGBButton.ButtonPushedFcn = createCallbackFcn(app, @AdobeRGBButtonPushed, true);
            app.AdobeRGBButton.Text = {'Adobe'; 'RGB'};
            app.CustomDefinedRGBButton = uibutton(app.ConverterPanel, 'push');
            app.CustomDefinedRGBButton.ButtonPushedFcn = createCallbackFcn(app, @CustomDefinedRGBButtonPushed, true);
            app.CustomDefinedRGBButton.Text = {'Custom'; 'Defined'; 'RGB'};
            app.XYZ_X = uieditfield(app.ConverterPanel, 'numeric');
            app.XYZ_X.Value = 100;
            app.XYZ_Y = uieditfield(app.ConverterPanel, 'numeric');
            app.XYZ_Y.Value = 100;
            app.XYZ_Z = uieditfield(app.ConverterPanel, 'numeric');
            app.XYZ_Z.Value = 100;
            app.Luv_L = uieditfield(app.ConverterPanel, 'numeric');
            app.Luv_u = uieditfield(app.ConverterPanel, 'numeric');
            app.Luv_v = uieditfield(app.ConverterPanel, 'numeric');
            app.Lab_L = uieditfield(app.ConverterPanel, 'numeric');
            app.Lab_a = uieditfield(app.ConverterPanel, 'numeric');
            app.Lab_b = uieditfield(app.ConverterPanel, 'numeric');
            app.sRGB_R = uieditfield(app.ConverterPanel, 'numeric');
            app.sRGB_G = uieditfield(app.ConverterPanel, 'numeric');
            app.sRGB_B = uieditfield(app.ConverterPanel, 'numeric');
            app.AdobeRGB_R = uieditfield(app.ConverterPanel, 'numeric');
            app.AdobeRGB_G = uieditfield(app.ConverterPanel, 'numeric');
            app.AdobeRGB_B = uieditfield(app.ConverterPanel, 'numeric');
            app.RGB_R = uieditfield(app.ConverterPanel, 'numeric');
            app.RGB_G = uieditfield(app.ConverterPanel, 'numeric');
            app.RGB_B = uieditfield(app.ConverterPanel, 'numeric');
            app.xyYButton = uibutton(app.ConverterPanel, 'push');
            app.xyYButton.ButtonPushedFcn = createCallbackFcn(app, @xyYButtonPushed, true);
            app.xyYButton.Text = 'xyY';
            app.xyY_x = uieditfield(app.ConverterPanel, 'numeric');
            app.xyY_x.Value = 100;
            app.xyY_y = uieditfield(app.ConverterPanel, 'numeric');
            app.xyY_y.Value = 100;
            app.xyY_Y = uieditfield(app.ConverterPanel, 'numeric');
            app.xyY_Y.Value = 100;
            app.SpectrumPanel = uipanel(app.ConverterPanel);
            app.SpectrumPanel.Title = 'Spectrum';
            app.BlackbodyradiationButton = uibutton(app.SpectrumPanel, 'push');
            app.BlackbodyradiationButton.ButtonPushedFcn = createCallbackFcn(app, @BlackbodyradiationButtonPushed, true);
            app.BlackbodyradiationButton.Text = 'Blackbody radiation';
            app.SpectrumFileButton = uibutton(app.SpectrumPanel, 'push');
            app.SpectrumFileButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumFileButtonPushed, true);
            app.SpectrumFileButton.Text = 'SpectrumFile';
            app.NormalizeYto100CheckBox = uicheckbox(app.SpectrumPanel);
            app.NormalizeYto100CheckBox.Text = 'Normalize Y to 100';
            app.NormalizeYto100CheckBox.Value = true;
            app.TKEditFieldLabel = uilabel(app.SpectrumPanel);
            app.TKEditFieldLabel.Text = 'T (K)';
            app.BlackTemp = uieditfield(app.SpectrumPanel, 'numeric');
            app.BlackTemp.Limits = [0 Inf];
            app.BlackTemp.Value = 4000;
            app.CustomdefinedRGBspacePanel = uipanel(app.ConverterPanel);
            app.CustomdefinedRGBspacePanel.Title = 'Custom defined RGB space';
            app.R_X = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.R_X.Value = 0.63;
            app.R_Y = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.R_Y.Value = 0.33;
            app.R_Z = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.R_Z.Value = 0.33;
            app.G_X = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.G_X.Value = 0.3;
            app.G_Y = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.G_Y.Value = 0.6;
            app.G_Z = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.G_Z.Value = 0.1;
            app.B_X = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.B_X.Value = 0.15;
            app.B_Y = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.B_Y.Value = 0.06;
            app.B_Z = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.B_Z.Value = 0.79;
            app.W_X = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.W_X.Value = 95.047;
            app.W_Y = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.W_Y.Value = 100;
            app.W_Z = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.W_Z.Value = 108.883;
            app.XYZLabel = uilabel(app.CustomdefinedRGBspacePanel);
            app.XYZLabel.Text = 'X                    Y                 Z';
            app.PrimaryRedGreenBlueWhiteLabel = uilabel(app.CustomdefinedRGBspacePanel);
            app.PrimaryRedGreenBlueWhiteLabel.Text = {'Primary'; ''; 'Red'; ''; 'Green'; ''; 'Blue'; ''; 'White'};
            app.GammaEditFieldLabel = uilabel(app.CustomdefinedRGBspacePanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.CustomdefinedRGBspacePanel, 'numeric');
            app.GammaEditField.Limits = [0 Inf];
            app.GammaEditField.Value = 1;
            app.ColorvisiondeficiencyPanel = uipanel(app.ColorConversionToolbox);
            app.ColorvisiondeficiencyPanel.Title = 'Color vision deficiency';
            app.ColorShow = uiaxes(app.ColorvisiondeficiencyPanel);
            title(app.ColorShow, 'Color')
            app.ColorShow.Visible = 'off';
            app.NormalLabel = uilabel(app.ColorvisiondeficiencyPanel);
            app.NormalLabel.Text = 'Normal';
            app.ColorShow_2 = uiaxes(app.ColorvisiondeficiencyPanel);
            title(app.ColorShow_2, 'Color')
            app.ColorShow_2.Visible = 'off';
            app.ColorShow_3 = uiaxes(app.ColorvisiondeficiencyPanel);
            title(app.ColorShow_3, 'Color')
            app.ColorShow_3.Visible = 'off';
            app.ProtanopiaLabel = uilabel(app.ColorvisiondeficiencyPanel);
            app.ProtanopiaLabel.Text = 'Protanopia';
            app.DeuteranopiaLabel = uilabel(app.ColorvisiondeficiencyPanel);
            app.DeuteranopiaLabel.Text = 'Deuteranopia';
            app.TritanopiaLabel = uilabel(app.ColorvisiondeficiencyPanel);
            app.TritanopiaLabel.Text = 'Tritanopia';
            app.ColorShow_4 = uiaxes(app.ColorvisiondeficiencyPanel);
            title(app.ColorShow_4, 'Color')
            app.ColorShow_4.Visible = 'off';
            app.LoadImageFileButton = uibutton(app.ColorvisiondeficiencyPanel, 'push');
            app.LoadImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageFileButtonPushed, true);
            app.LoadImageFileButton.Text = 'Load Image File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ColorConverter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorConversionToolbox)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorConversionToolbox)
        end
    end
end